classdef send_ble < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        InitializeTab             matlab.ui.container.Tab
        Lamp_2                    matlab.ui.control.Lamp
        Lamp_2Label               matlab.ui.control.Label
        Lamp                      matlab.ui.control.Lamp
        Label                     matlab.ui.control.Label
        EnableControlPanelButton  matlab.ui.control.Button
        InitializeBLEButton       matlab.ui.control.Button
        ControlPanelTab           matlab.ui.container.Tab
        Lamp_5                    matlab.ui.control.Lamp
        Lamp_4                    matlab.ui.control.Lamp
        Lamp_3                    matlab.ui.control.Lamp
        Knob_3                    matlab.ui.control.Knob
        Knob_2                    matlab.ui.control.Knob
        Knob                      matlab.ui.control.Knob
    end


    properties (Access = public)
        serialAddress = "/dev/tty.usbserial-DN03GO3K";
        bleAddress = "98B22EEE-F9CA-420E-9F0B-7976E1D0D356";
        
        %% StartButtonPushed properties
        serialMonitor;      % Arduino Mini ("Arduino 1") serial monitor
        iot;                % Arduino Nano ("Arduino 2") BLE object
        
        muscleOneReading = 0;   % int ∈[0,1023]: voltage at A0
        muscleTwoReading = 0;   % int ∈[0,1023]: voltage at A1
        muscleThreeReading = 0; % int ∈[0,1023]: voltage at A2
        
        muscleOneVoltage = 0;   % int ∈[0,1023]: voltage stimulating muscle one 
        muscleTwoVoltage = 0;   % int ∈[0,1023]: voltage stimulating muscle two
        muscleThreeVoltage = 0; % int ∈[0,1023]: voltage stimulating muscle three
        
        muscleOneVoltageChar;   % characteristic object - writing to this stimulates muscle one
        muscleTwoVoltageChar;   % characteristic object - writing to this stimulates muscle one
        muscleThreeVoltageChar; % characteristic object - writing to this stimulates muscle one
        
    end
    
    methods (Access = private)

       function muscleOneHandler(app, voltageReading)
            knobValue = round(app.Knob.Value);
            
            % If necessary, adjust voltage to muscle one
            if (voltageReading == 0) && (app.muscleOneVoltage ~= 0)
                write(app.muscleOneVoltageChar, 0);
                app.muscleOneVoltage = 0;
                app.Lamp_3.Color = [0.651 0.651 0.651];
            elseif (voltageReading == 1) && (app.muscleOneVoltage ~= knobValue)
                write(app.muscleOneVoltageChar, knobValue)
                app.muscleOneVoltage = knobValue;
                app.Lamp_3.Color = [0.39 0.83 0.07];
            end
       end
       
       function muscleTwoHandler(app, voltageReading)
            knobValue = round(app.Knob_2.Value);
            
            % If necessary, adjust voltage to muscle two
            if (voltageReading == 0) && (app.muscleTwoVoltage ~= 0)
                write(app.muscleTwoVoltageChar, 0);
                app.muscleTwoVoltage = 0;
                app.Lamp_4.Color = [0.651 0.651 0.651];
            elseif (voltageReading == 1) && (app.muscleTwoVoltage ~= knobValue)
                write(app.muscleTwoVoltageChar, knobValue)
                app.muscleTwoVoltage = knobValue;
                app.Lamp_4.Color = [0.39 0.83 0.07];
            end
       end
       
       function muscleThreeHandler(app, voltageReading)
            knobValue = round(app.Knob_2.Value);
            
            % If necessary, adjust voltage to muscle three
            if (voltageReading == 0) && (app.muscleThreeVoltage ~= 0)
                write(app.muscleThreeVoltageChar, 0);
                app.muscleThreeVoltage = 0;
                app.Lamp_5.Color = [0.651 0.651 0.651];
            elseif (voltageReading == 1) && (app.muscleThreeVoltage ~= knobValue)
                write(app.muscleThreeVoltageChar, knobValue)
                app.muscleThreeVoltage = knobValue;
                app.Lamp_5.Color = [0.39 0.83 0.07];
            end
       end

        
        function loop(app)
            % Retrieve encoded value from serial monitor
            serialMonitorValue = convertStringsToChars(app.serialMonitor.readline);

            % Call each handler with either a 0 or 1
            if numel(serialMonitorValue) == 3
                muscleOneHandler(app, str2double(serialMonitorValue(1)));
                muscleTwoHandler(app, str2double(serialMonitorValue(2)));
                muscleThreeHandler(app, str2double(serialMonitorValue(3)));
            end
            
            % Flush serial monitor so next line can be retrieved
            app.serialMonitor.flush;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc; clear; instrreset;
        end

        % Button pushed function: InitializeBLEButton
        function InitializeBLEButtonPushed(app, event)
            % Initalize serial monitor  
            app.serialMonitor = serialport(app.serialAddress, 9600);
            
            % Initialize ble object
            app.iot = ble(app.bleAddress);
        
            % Initialize ble characteristics
            muscleVoltageServiceUUID = "19B10000-E8F2-537E-4F6C-D204768A1214";
            app.muscleOneVoltageChar = characteristic(app.iot,muscleVoltageServiceUUID,"00B1");
            app.muscleTwoVoltageChar = characteristic(app.iot,muscleVoltageServiceUUID,"00B2");
            app.muscleThreeVoltageChar = characteristic(app.iot,muscleVoltageServiceUUID,"00B3");
            
            % Write 0 to muscle voltage charactericts
            write(app.muscleOneVoltageChar, 0);
            write(app.muscleTwoVoltageChar, 0);
            write(app.muscleThreeVoltageChar, 0);
     
            % Indicate 'ready' status of BLE object
            app.Lamp_2.Color = [0.39 0.83 0.07];
            
            % Unlock start button
            app.EnableControlPanelButton.BackgroundColor = [0.96 0.96 0.96];
            app.EnableControlPanelButton.FontColor = [0 0 0];   
        end

        % Button pushed function: EnableControlPanelButton
        function EnableControlPanelButtonPushed(app, event)
            % Break off into main control flow
            app.Lamp.Color = [0.39 0.83 0.07];
            while 1
                loop(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InitializeTab = uitab(app.TabGroup);
            app.InitializeTab.Title = 'Initialize';
            app.InitializeBLEButton = uibutton(app.InitializeTab, 'push');
            app.InitializeBLEButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeBLEButtonPushed, true);
            app.InitializeBLEButton.Text = 'Initialize BLE';
            app.EnableControlPanelButton = uibutton(app.InitializeTab, 'push');
            app.EnableControlPanelButton.ButtonPushedFcn = createCallbackFcn(app, @EnableControlPanelButtonPushed, true);
            app.EnableControlPanelButton.Text = 'Enable Control Panel';
            app.Label = uilabel(app.InitializeTab);
            app.Label.Text = '';
            app.Lamp = uilamp(app.InitializeTab);
            app.Lamp_2Label = uilabel(app.InitializeTab);
            app.Lamp_2Label.Text = '';
            app.Lamp_2 = uilamp(app.InitializeTab);
            app.ControlPanelTab = uitab(app.TabGroup);
            app.ControlPanelTab.Title = 'Control Panel';
            app.Knob = uiknob(app.ControlPanelTab, 'continuous');
            app.Knob.Limits = [0 128];
            app.Knob.MajorTicks = [0 32 64 96 128];
            app.Knob_2 = uiknob(app.ControlPanelTab, 'continuous');
            app.Knob_2.Limits = [0 128];
            app.Knob_2.MajorTicks = [0 32 64 96 128];
            app.Knob_3 = uiknob(app.ControlPanelTab, 'continuous');
            app.Knob_3.Limits = [0 128];
            app.Knob_3.MajorTicks = [0 32 64 96 128];
            app.Knob_3.MajorTickLabels = {'0', '16', '32', '48', '64', '80', '96', '112', '128'};
            app.Lamp_3 = uilamp(app.ControlPanelTab);
            app.Lamp_4 = uilamp(app.ControlPanelTab);
            app.Lamp_5 = uilamp(app.ControlPanelTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = send_ble

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end